classdef Eyepredict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEG_GUIUIFigure        matlab.ui.Figure
        VPLabel                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        TasksPanel             matlab.ui.container.Panel
        Resting                matlab.ui.control.Button
        resting_lamp           matlab.ui.control.Lamp
        ImpedanzenENDELabel    matlab.ui.control.Label
        visualWM               matlab.ui.control.Button
        visualWM_lamp          matlab.ui.control.Lamp
        visualWM_ex            matlab.ui.control.Button
        Dots_ex                matlab.ui.control.Button
        Dots                   matlab.ui.control.Button
        Dots_lamp              matlab.ui.control.Lamp
        ImpedanzenLabel        matlab.ui.control.Label
        Dots_2                 matlab.ui.control.Button
        Dots_3                 matlab.ui.control.Button
        Movie                  matlab.ui.control.Button
        Movie_lamp             matlab.ui.control.Lamp
        WISC                   matlab.ui.control.Button
        AntiSacc               matlab.ui.control.Button
        Movie_2                matlab.ui.control.Button
        Reading                matlab.ui.control.Button
        WISC_2                 matlab.ui.control.Button
        Dots_lamp_2            matlab.ui.control.Lamp
        Dots_lamp_3            matlab.ui.control.Lamp
        WISC_lamp              matlab.ui.control.Lamp
        WISC_lamp_2            matlab.ui.control.Lamp
        AntiSacc_ex            matlab.ui.control.Button
        AntiSacc_lamp          matlab.ui.control.Lamp
        Movie_lamp_2           matlab.ui.control.Lamp
        Reading_lamp           matlab.ui.control.Lamp
        WISC_ex                matlab.ui.control.Button
        VerbindungPanel        matlab.ui.container.Panel
        ConnectEEG             matlab.ui.control.Button
        EEGVerbundenLampLabel  matlab.ui.control.Label
        EEGVerbundenLamp       matlab.ui.control.Lamp
        ETVerbundenLampLabel   matlab.ui.control.Label
        ETVerbundenLamp        matlab.ui.control.Lamp
        VButton                matlab.ui.control.Button
        BeendenButton_2        matlab.ui.control.Button
    end

    
    properties (Access = public)
        ID; % Description
        run; % Description
        useEL_Ex;
        Status=[0 0]; % Description
        Done=[0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
        savePath; % Description
    end
    
    
    methods (Access = private)
        function results = refreshStatus(app)
            app.VButton.Enable ='off';
            if ispc
                C = evalc('!ping -n 2 100.1.1.1');
            elseif isunix
                C = evalc('!ping -w 2 100.1.1.1');
            end
            loss = regexp(C, '([0-9]*)%.*loss', 'tokens');
            connected = ~isempty(loss) && str2double(loss{1}{1})==0;
            if connected
                app.Status(1) = 1;
            else
                app.Status(1) = 0;
            end
            if ~NetStation('Synchronize')
                app.Status(2) = 1;
            else
                app.Status(2) = 0;
            end
            refreshLights(app);
            app.VButton.Enable ='on';
        end
        
        function results = deactivateControls(app)
            app.Resting.Enable = 'off';
            app.Dots.Enable='off';
            app.Dots_ex.Enable='off';
            app.Dots_2.Enable='off';
            app.Dots_3.Enable='off';
            app.Movie.Enable = 'off';
            app.Movie_2.Enable = 'off';
            app.WISC.Enable = 'off';
            app.WISC_2.Enable = 'off';
            app.AntiSacc.Enable = 'off';  
            app.AntiSacc_ex.Enable = 'off';
            app.visualWM.Enable='off';
            app.visualWM_ex.Enable='off';
            app.Reading.Enable ='off';
        end
        
        function results = activateControls(app)
            app.Resting.Enable = 'on';
            app.Dots.Enable='on';
            app.Dots_ex.Enable='on';
            app.Dots_2.Enable='on';
            app.Dots_3.Enable='on';
            app.Movie.Enable = 'on';
            app.Movie_2.Enable = 'on';
            app.WISC.Enable = 'on';
            app.WISC_2.Enable = 'on';
            app.AntiSacc.Enable = 'on';
            app.AntiSacc_ex.Enable = 'on';
            app.visualWM.Enable='on';
            app.visualWM_ex.Enable='on';
            app.Reading.Enable ='on';
        end
        
    end
    
    methods (Access = public)
        
        function results = refresh(app)
            set(app.Label, 'Text', app.ID{1});
            refreshLights(app);
        end
        
        function results = refreshLights(app)
            if app.Status(1)==1
                app.ETVerbundenLamp.Color = [0 1 0];
            else
                app.ETVerbundenLamp.Color = [1 0 0];
            end
            if app.Status(2)==1
                app.EEGVerbundenLamp.Color = [0 1 0];
            else
                app.EEGVerbundenLamp.Color = [1 0 0];
            end
            if app.Done(1)==1
                app.resting_lamp.Color = [0 1 0];
            else
                app.resting_lamp.Color = [1 1 1];
            end
           if app.Done(2)==1
                app.Dots_lamp.Color = [0 1 0];
            else
                app.Dots_lamp.Color = [1 1 1];
           end
            if app.Done(3)==1
                app.Movie_lamp.Color = [0 1 0];
            else
                app.Movie_lamp.Color = [1 1 1];
            end             
            if app.Done(4)==1
                app.WISC_lamp.Color = [0 1 0];
            else
                app.WISC_lamp.Color = [1 1 1];
            end            
            if app.Done(5)==1
                app.AntiSacc_lamp.Color = [0 1 0];
            else
                app.AntiSacc_lamp.Color = [1 1 1];
            end               
            if app.Done(6)==1
                app.Dots_lamp_2.Color = [0 1 0];
            else
                app.Dots_lamp_2.Color = [1 1 1];
            end
             if app.Done(7)==1
                app.visualWM_lamp.Color = [0 1 0];
            else
                app.visualWM_lamp.Color = [1 1 1];
             end 
             if app.Done(8)==1
                app.Movie_lamp_2.Color = [0 1 0];
            else
                app.Movie_lamp_2.Color = [1 1 1];
             end            
            if app.Done(9)==1
                app.Reading_lamp.Color = [0 1 0];
            else
                app.Reading_lamp.Color = [1 1 1];
            end

            if app.Done(10)==1
                app.Dots_lamp_3.Color = [0 1 0];
            else
                app.Dots_lamp_3.Color = [1 1 1];
            end
         
             if app.Done(11)==1
                app.WISC_lamp_2.Color = [0 1 0];
            else
                app.WISC_lamp_2.Color = [1 1 1];
             end
            
            results=true;
        end
        
        function results = saveInfo(app)
            Done = app.Done;
            save([app.savePath,'/',app.ID{1}, '_metafile'],'Done','-append');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refreshStatus(app);
        end

        % Button pushed function: Resting
        function RestingButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Resting EEG...');
            try
                app.resting_lamp.Color = [1 1 0];
                if app.Done(1)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(1)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(1)=0;
                Resting_EEG
                app.Done(1)=1;
                saveInfo(app);
            catch
                if not(app.Done(1))
                    app.Done(1)=0;
                end
                disp('FEHLER');
            end
            
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: AntiSacc
        function AntiSaccButtonPushed(app, event)
            if app.Status(1)~=1
                warndlg('ET is not connected! Antisaccade only works with ET','ERROR');
            else
                deactivateControls(app);
                saveInfo(app);
                clc;
                disp('Starting Antisaccade Paradigma...');
                try
                    app.AntiSacc_lamp.Color = [1 1 0];
                    if app.Done(5)==1
                        mbo=msgbox('Warning: This task already has been performed succesfuly, do you really want to redo the task?');
                    end
                    app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                    if app.Done(5)==1
                        close(mbo);
                    end
                    if (isempty(app.run)); error('X');end;
                    app.Done(5)=0;
                    AntiSaccade
                    app.Done(5)=1;
                    saveInfo(app);
                catch
                    if not(app.Done(3))
                        app.Done(5)=0;
                    end
                    disp('FEHLER');
                end
                refreshLights(app);
                activateControls(app);
                msgbox('Before correcting subjects impedances, first measure the current impedances and press "save and close".');
                
            end;
        end

        % Button pushed function: AntiSacc_ex
        function AntiSacc_exButtonPushed(app, event)
            deactivateControls(app);
            if app.Status(1)~=1
                warndlg('ET is not connected! Antisaccade only works with ET','ERROR');
            else
                clc;
                disp('Starting Antisaccade Example...');
                AntiSaccade_Example
                clc;
                disp('Antisaccade Example finished!');
            end
            activateControls(app);
        end

        % Button pushed function: ConnectEEG
        function ConnectEEGButtonPushed(app, event)
            try
                app.EEGVerbundenLamp.Color = [1 1 0];
                try
                    i = NetStation('Synchronize');
                    if i == 0
                        disp('already connected');
                    else
                        disp('need to connect');
                    end
                catch
                end
                [status,info] = NetStation('Connect','100.1.1.3',55513);
                WaitSecs(1);
                if status ~= 0
                    error(info);
                end
                app.Status(2)=1;
            catch
                disp('FEHLER');
                app.Status(2)=0;
            end
            refreshLights(app);
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            refreshStatus(app);
        end

        % Button pushed function: visualWM
        function visualWMButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Visual_WM Paradigma...');
            try
                app.visualWM_lamp.Color = [1 1 0];
                if app.Done(7)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(7)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(7)=0;
                Run_ChangeDetection;
                app.Done(7)=1;
                saveInfo(app);
            catch
                if not(app.Done(7))
                    app.Done(7)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: visualWM_ex
        function visualWM_exButtonPushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting VisualWM Example...');
            Run_ChangeDetection_Example;
            activateControls(app);
        end

        % Button pushed function: Dots
        function DotsButtonPushed(app, event)
              deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Dots...');
            %try
                app.Dots_lamp.Color = [1 1 0];
                if app.Done(2)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(2)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(2)=0;
               Dots
                app.Done(2)=1;
                saveInfo(app);
          % catch
           %     if not(app.Done(2))
         %           app.Done(2)=0;
        %        end
        %        disp('FEHLER');
       %     end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: Dots_ex
        function Dots_exButtonPushed(app, event)
             deactivateControls(app);
            clc;
            disp('Starting Dots Example...');
             Dots_ex;
            activateControls(app);
        end

        % Button pushed function: Dots_2
        function Dots_2ButtonPushed(app, event)
              deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Dots2...');
            %try
                app.Dots_lamp_2.Color = [1 1 0];
                if app.Done(6)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(6)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(6)=0;
               Dots
                app.Done(6)=1;
                saveInfo(app);
                
                refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: Dots_3
        function Dots_3ButtonPushed(app, event)
                  deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Dots3...');
            %try
                app.Dots_lamp_3.Color = [1 1 0];
                if app.Done(10)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(10)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(10)=0;
               Dots
                app.Done(10)=1;
                saveInfo(app);
                refreshLights(app);
            activateControls(app);
        end

        % Callback function
        function visualWM_2ButtonPushed(app, event)
                  deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Visual_WM Paradigma...');
            try
                app.visualWM_lamp.Color = [1 1 0];
                if app.Done(7)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(7)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(7)=0;
                Run_ChangeDetection;
                app.Done(7)=1;
                saveInfo(app);
            catch
                if not(app.Done(7))
                    app.Done(7)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: Movie
        function MovieButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Movie...');
            try
                app.Movie_lamp.Color = [1 1 0];
                if app.Done(3)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(3)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(3)=0;
                videoNicolas
                app.Done(3)=1;
                saveInfo(app);
            catch
                if not(app.Done(3))
                    app.Done(3)=0;
                end
                disp('FEHLER');
            end
            
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: WISC_2
        function WISC_2ButtonPushed(app, event)
                   deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Processing Speed Paradigma...');
            try
                app.WISC_lamp_2.Color = [1 1 0];
                if app.Done(11)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(11)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=2;
                app.Done(11)=0;
                WISC_ProcessingSpeed_paradigm
                app.Done(11)=1;
                saveInfo(app);
            catch
                if not(app.Done(11))
                    app.Done(11)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: Reading
        function ReadingButtonPushed(app, event)
                 deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Reading...');
            try
                app.Movie_lamp.Color = [1 1 0];
                if app.Done(9)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(9)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(9)=0;
              runNR
                app.Done(9)=1;
                saveInfo(app);
            catch
                if not(app.Done(9))
                    app.Done(9)=0;
                end
                disp('FEHLER');
            end
            
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: Movie_2
        function Movie_2ButtonPushed(app, event)
                 deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Movie...');
            try
                app.Movie_lamp.Color = [1 1 0];
                if app.Done(8)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(8)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(8)=0;
                videoNicolas2
                app.Done(8)=1;
                saveInfo(app);
            catch
                if not(app.Done(8))
                    app.Done(8)=0;
                end
                disp('FEHLER');
            end
            
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: WISC
        function WISCButtonPushed(app, event)
                   deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Processing Speed Paradigma...');
            try
                app.WISC_lamp.Color = [1 1 0];
                if app.Done(4)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(4)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=1;
                app.Done(4)=0;
                WISC_ProcessingSpeed_paradigm
                app.Done(4)=1;
                saveInfo(app);
                         catch
                             if not(app.Done(4))
                                 app.Done(4)=0;
                             end
                            disp('FEHLER');
                         end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: BeendenButton_2
        function BeendenButton_2Pushed(app, event)
               
            choice = questdlg('Wirklich das Experiment Beenden?', ...
               	'ACHTUNG', ...
               	'Ja','Nein','Nein');
            % Handle response
            switch choice
                case 'Ja'
                    close all force;
                    try
                        NetStation('StopRecording');
                        NetStation('Disconnect');
                    catch
                    end
                    try
                        Eyelink('StopRecording');
                    catch
                    end
                    sca;
                case 'Nein'
                    return;
            end
            msgbox('3D Scan der Versuchsperson nicht vergessen!');
        end

        % Size changed function: VerbindungPanel
        function VerbindungPanelSizeChanged(app, event)
            position = app.VerbindungPanel.Position;
            
        end

        % Button pushed function: WISC_ex
        function WISC_exButtonPushed(app, event)
                  deactivateControls(app);
            clc;
            disp('Starting Processing Speed Example...');
            WISC_ProcessingSpeed_paradigm_Example
            clc;
            disp('Processing Speed Example finished!');
            activateControls(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEG_GUIUIFigure = uifigure;
            app.EEG_GUIUIFigure.Name = 'EEG_GUI';
            setAutoResize(app, app.EEG_GUIUIFigure, false)
            app.VPLabel = uilabel(app.EEG_GUIUIFigure);
            app.VPLabel.Text = 'VP:';
            app.Label = uilabel(app.EEG_GUIUIFigure);
            app.Label.Text = '';
            app.TasksPanel = uipanel(app.EEG_GUIUIFigure);
            app.TasksPanel.Title = 'Tasks';
            app.Resting = uibutton(app.TasksPanel, 'push');
            app.Resting.ButtonPushedFcn = createCallbackFcn(app, @RestingButtonPushed, true);
            app.Resting.Text = 'Resting EEG';
            app.resting_lamp = uilamp(app.TasksPanel);
            app.ImpedanzenENDELabel = uilabel(app.TasksPanel);
            app.ImpedanzenENDELabel.Text = 'Impedanzen / ENDE';
            app.visualWM = uibutton(app.TasksPanel, 'push');
            app.visualWM.ButtonPushedFcn = createCallbackFcn(app, @visualWMButtonPushed, true);
            app.visualWM.Text = 'Visual WM';
            app.visualWM_lamp = uilamp(app.TasksPanel);
            app.visualWM_ex = uibutton(app.TasksPanel, 'push');
            app.visualWM_ex.ButtonPushedFcn = createCallbackFcn(app, @visualWM_exButtonPushed, true);
            app.visualWM_ex.Text = 'Example';
            app.Dots_ex = uibutton(app.TasksPanel, 'push');
            app.Dots_ex.ButtonPushedFcn = createCallbackFcn(app, @Dots_exButtonPushed, true);
            app.Dots_ex.Text = 'Example';
            app.Dots = uibutton(app.TasksPanel, 'push');
            app.Dots.ButtonPushedFcn = createCallbackFcn(app, @DotsButtonPushed, true);
            app.Dots.Text = 'Dots';
            app.Dots_lamp = uilamp(app.TasksPanel);
            app.ImpedanzenLabel = uilabel(app.TasksPanel);
            app.ImpedanzenLabel.Text = 'Impedanzen';
            app.Dots_2 = uibutton(app.TasksPanel, 'push');
            app.Dots_2.ButtonPushedFcn = createCallbackFcn(app, @Dots_2ButtonPushed, true);
            app.Dots_2.Text = 'Dots_2';
            app.Dots_3 = uibutton(app.TasksPanel, 'push');
            app.Dots_3.ButtonPushedFcn = createCallbackFcn(app, @Dots_3ButtonPushed, true);
            app.Dots_3.Text = 'Dots_3';
            app.Movie = uibutton(app.TasksPanel, 'push');
            app.Movie.ButtonPushedFcn = createCallbackFcn(app, @MovieButtonPushed, true);
            app.Movie.Text = 'Movie';
            app.Movie_lamp = uilamp(app.TasksPanel);
            app.WISC = uibutton(app.TasksPanel, 'push');
            app.WISC.ButtonPushedFcn = createCallbackFcn(app, @WISCButtonPushed, true);
            app.WISC.Text = 'WISC';
            app.AntiSacc = uibutton(app.TasksPanel, 'push');
            app.AntiSacc.ButtonPushedFcn = createCallbackFcn(app, @AntiSaccButtonPushed, true);
            app.AntiSacc.Text = 'Antisaccade';
            app.Movie_2 = uibutton(app.TasksPanel, 'push');
            app.Movie_2.ButtonPushedFcn = createCallbackFcn(app, @Movie_2ButtonPushed, true);
            app.Movie_2.Text = 'Movie';
            app.Reading = uibutton(app.TasksPanel, 'push');
            app.Reading.ButtonPushedFcn = createCallbackFcn(app, @ReadingButtonPushed, true);
            app.Reading.Text = 'Reading';
            app.WISC_2 = uibutton(app.TasksPanel, 'push');
            app.WISC_2.ButtonPushedFcn = createCallbackFcn(app, @WISC_2ButtonPushed, true);
            app.WISC_2.Text = 'WISC';
            app.Dots_lamp_2 = uilamp(app.TasksPanel);
            app.Dots_lamp_3 = uilamp(app.TasksPanel);
            app.WISC_lamp = uilamp(app.TasksPanel);
            app.WISC_lamp_2 = uilamp(app.TasksPanel);
            app.AntiSacc_ex = uibutton(app.TasksPanel, 'push');
            app.AntiSacc_ex.ButtonPushedFcn = createCallbackFcn(app, @AntiSacc_exButtonPushed, true);
            app.AntiSacc_ex.Text = 'Example';
            app.AntiSacc_lamp = uilamp(app.TasksPanel);
            app.Movie_lamp_2 = uilamp(app.TasksPanel);
            app.Reading_lamp = uilamp(app.TasksPanel);
            app.WISC_ex = uibutton(app.TasksPanel, 'push');
            app.WISC_ex.ButtonPushedFcn = createCallbackFcn(app, @WISC_exButtonPushed, true);
            app.WISC_ex.Text = 'Example';
            app.VerbindungPanel = uipanel(app.EEG_GUIUIFigure);
            app.VerbindungPanel.Title = 'Verbindung';
            app.VerbindungPanel.SizeChangedFcn = createCallbackFcn(app, @VerbindungPanelSizeChanged, true);
            app.ConnectEEG = uibutton(app.VerbindungPanel, 'push');
            app.ConnectEEG.ButtonPushedFcn = createCallbackFcn(app, @ConnectEEGButtonPushed, true);
            app.ConnectEEG.Text = 'Verb.';
            app.EEGVerbundenLampLabel = uilabel(app.VerbindungPanel);
            app.EEGVerbundenLampLabel.Text = 'EEG Verbunden';
            app.EEGVerbundenLamp = uilamp(app.VerbindungPanel);
            app.ETVerbundenLampLabel = uilabel(app.VerbindungPanel);
            app.ETVerbundenLampLabel.Text = 'ET Verbunden';
            app.ETVerbundenLamp = uilamp(app.VerbindungPanel);
            app.VButton = uibutton(app.VerbindungPanel, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.BeendenButton_2 = uibutton(app.EEG_GUIUIFigure, 'push');
            app.BeendenButton_2.ButtonPushedFcn = createCallbackFcn(app, @BeendenButton_2Pushed, true);
            app.BeendenButton_2.Text = 'Beenden';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Eyepredict()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEG_GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEG_GUIUIFigure)
        end
    end
end