classdef MAIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEG_GUIUIFigure                 matlab.ui.Figure
        VPLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TasksPanel                      matlab.ui.container.Panel
        Resting                         matlab.ui.control.Button
        Questionnaire                   matlab.ui.control.Button
        SurrSup1                        matlab.ui.control.Button
        VisLearn1                       matlab.ui.control.Button
        VisLearn2                       matlab.ui.control.Button
        SimpleSpan                      matlab.ui.control.Button
        AntiSacc                        matlab.ui.control.Button
        ProcSpeed2                      matlab.ui.control.Button
        SurrSup2                        matlab.ui.control.Button
        VisLearn3                       matlab.ui.control.Button
        ProcSpeed1                      matlab.ui.control.Button
        VisLearn1_ex                    matlab.ui.control.Button
        VisLearn2_ex                    matlab.ui.control.Button
        AntiSacc_ex                     matlab.ui.control.Button
        ProcSpeed2_ex                   matlab.ui.control.Button
        VisLearn3_ex                    matlab.ui.control.Button
        ProcSpeed1_ex                   matlab.ui.control.Button
        quest_lamp                      matlab.ui.control.Lamp
        resting_lamp                    matlab.ui.control.Lamp
        vislearn1_lamp                  matlab.ui.control.Lamp
        procspeed1_lamp                 matlab.ui.control.Lamp
        surrsupp1_lamp                  matlab.ui.control.Lamp
        vislearn2_lamp                  matlab.ui.control.Lamp
        antisacc_lamp                   matlab.ui.control.Lamp
        simplespan_lamp                 matlab.ui.control.Lamp
        surrsupp3_lamp                  matlab.ui.control.Lamp
        procspeed2_lamp                 matlab.ui.control.Lamp
        vislearn3_lamp                  matlab.ui.control.Lamp
        ImpedanzenPauseImpedanzenLabel  matlab.ui.control.Label
        ImpedanzenPauseImpedanzenLabel_2  matlab.ui.control.Label
        ImpedanzenENDELabel             matlab.ui.control.Label
        Simple_Span_ex                  matlab.ui.control.Button
        visualWM                        matlab.ui.control.Button
        visualWM_lamp                   matlab.ui.control.Lamp
        visualWM_ex                     matlab.ui.control.Button
        surrsupp2_lamp                  matlab.ui.control.Lamp
        SurroundSuppression2Button      matlab.ui.control.Button
        LongtermButton                  matlab.ui.control.Button
        LongtermButton_2                matlab.ui.control.Button
        VerbindungPanel                 matlab.ui.container.Panel
        ConnectEEG                      matlab.ui.control.Button
        EEGVerbundenLampLabel           matlab.ui.control.Label
        EEGVerbundenLamp                matlab.ui.control.Lamp
        ETVerbundenLampLabel            matlab.ui.control.Label
        ETVerbundenLamp                 matlab.ui.control.Lamp
        VButton                         matlab.ui.control.Button
        BeendenButton_2                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        ID; % Description
        run; % Description
        useEL_Ex;
        Status=[0 0]; % Description
        Done=[0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
        savePath; % Description
    end
    
    
    methods (Access = private)
        function results = refreshStatus(app)
            app.VButton.Enable ='off';
            if ispc
                C = evalc('!ping -n 2 100.1.1.1');
            elseif isunix
                C = evalc('!ping -w 2 100.1.1.1');
            end
            loss = regexp(C, '([0-9]*)%.*loss', 'tokens');
            connected = ~isempty(loss) && str2double(loss{1}{1})==0;
            if connected
                app.Status(1) = 1;
            else
                app.Status(1) = 0;
            end
            if ~NetStation('Synchronize')
                app.Status(2) = 1;
            else
                app.Status(2) = 0;
            end
            refreshLights(app);
            app.VButton.Enable ='on';
        end
        
        function results = deactivateControls(app)
            app.Resting.Enable = 'off';
            app.Questionnaire.Enable = 'off';
            app.SurrSup1.Enable = 'off';
            app.VisLearn1.Enable = 'off';
            app.VisLearn2.Enable = 'off';
            %     app.ContrCh1.Enable = 'off';
            app.SimpleSpan.Enable = 'off';
            app.AntiSacc.Enable = 'off';
            app.visualWM.Enable = 'off';
            app.ProcSpeed2.Enable = 'off';
            app.SurrSup2.Enable = 'off';
            app.VisLearn3.Enable = 'off';
            %    app.ContrChange2.Enable = 'off';
            app.ProcSpeed1.Enable = 'off';
            %     app.ContrCh3.Enable = 'off';
            app.VisLearn1_ex.Enable = 'off';
            app.VisLearn2_ex.Enable = 'off';
            app.VisLearn3_ex.Enable = 'off';
            %   app.ContrCh1_ex.Enable = 'off';
            app.AntiSacc_ex.Enable = 'off';
            app.ProcSpeed2_ex.Enable = 'off';
            %     app.ContrCh2_ex.Enable = 'off';
            app.ProcSpeed1_ex.Enable = 'off';
            %    app.ContrCh3_ex.Enable = 'off';
            app.Simple_Span_ex.Enable= 'off';
            app.SurroundSuppression2Button.Enable = 'off';
            app.LongtermButton.Enable='off';
            app.LongtermButton_2.Enable='off';
            app.visualWM.Enable='off';
            app.visualWM_ex.Enable='off';
            
        end
        
        function results = activateControls(app)
            app.Resting.Enable = 'on';
            app.Questionnaire.Enable = 'on';
            app.SurrSup1.Enable = 'on';
            app.VisLearn1.Enable = 'on';
            app.VisLearn2.Enable = 'on';
            %     app.ContrCh1.Enable = 'on';
            app.SimpleSpan.Enable = 'on';
            app.AntiSacc.Enable = 'on';
            app.visualWM.Enable = 'on';
            app.ProcSpeed2.Enable = 'on';
            app.SurrSup2.Enable = 'on';
            app.VisLearn3.Enable = 'on';
            %   app.ContrChange2.Enable = 'on';
            app.ProcSpeed1.Enable = 'on';
            %  app.ContrCh3.Enable = 'on';
            app.VisLearn1_ex.Enable = 'on';
            app.VisLearn2_ex.Enable = 'on';
            app.VisLearn3_ex.Enable = 'on';
            %  app.ContrCh1_ex.Enable = 'on';
            app.AntiSacc_ex.Enable = 'on';
            app.ProcSpeed2_ex.Enable = 'on';
            %    app.ContrCh2_ex.Enable = 'on';
            app.ProcSpeed1_ex.Enable = 'on';
            %    app.ContrCh3_ex.Enable = 'on';
            app.Simple_Span_ex.Enable= 'on';
            app.SurroundSuppression2Button.Enable = 'on';
            app.LongtermButton.Enable='on';
            app.LongtermButton_2.Enable='on';
            app.visualWM.Enable='on';
            app.visualWM_ex.Enable='on';
            
        end
        
    end
    
    methods (Access = public)
        
        function results = refresh(app)
            set(app.Label, 'Text', app.ID{1});
            refreshLights(app);
        end
        
        function results = refreshLights(app)
            if app.Status(1)==1
                app.ETVerbundenLamp.Color = [0 1 0];
            else
                app.ETVerbundenLamp.Color = [1 0 0];
            end
            if app.Status(2)==1
                app.EEGVerbundenLamp.Color = [0 1 0];
            else
                app.EEGVerbundenLamp.Color = [1 0 0];
            end
            
            if app.Done(1)==1
                app.quest_lamp.Color = [0 1 0];
            else
                app.quest_lamp.Color = [1 1 1];
            end
            if app.Done(2)==1
                app.resting_lamp.Color = [0 1 0];
            else
                app.resting_lamp.Color = [1 1 1];
            end
            if app.Done(3)==1
                app.vislearn1_lamp.Color = [0 1 0];
            else
                app.vislearn1_lamp.Color = [1 1 1];
            end
            if app.Done(4)==1
                app.procspeed1_lamp.Color = [0 1 0];
            else
                app.procspeed1_lamp.Color = [1 1 1];
            end
            if app.Done(5)==1
                app.surrsupp1_lamp.Color = [0 1 0];
            else
                app.surrsupp1_lamp.Color = [1 1 1];
            end
            %            if app.Done(6)==1
            %                app.contrch1_lamp.Color = [0 1 0];
            %            else
            %                app.contrch1_lamp.Color = [1 1 1];
            %            end
            if app.Done(7)==1
                app.vislearn2_lamp.Color = [0 1 0];
            else
                app.vislearn2_lamp.Color = [1 1 1];
            end
            if app.Done(8)==1
                app.antisacc_lamp.Color = [0 1 0];
            else
                app.antisacc_lamp.Color = [1 1 1];
            end
            if app.Done(9)==1
                app.simplespan_lamp.Color = [0 1 0];
            else
                app.simplespan_lamp.Color = [1 1 1];
            end
            if app.Done(10)==1
                app.surrsupp2_lamp.Color = [0 1 0];
            else
                app.surrsupp2_lamp.Color = [1 1 1];
            end
            if app.Done(16)==1
                app.surrsupp3_lamp.Color = [0 1 0];
            else
                app.surrsupp3_lamp.Color = [1 1 1];
            end
            if app.Done(11)==1
                app.procspeed2_lamp.Color = [0 1 0];
            else
                app.procspeed2_lamp.Color = [1 1 1];
            end
            %            if app.Done(12)==1
            %                app.contrch2_lamp.Color = [0 1 0];
            %            else
            %                app.contrch2_lamp.Color = [1 1 1];
            %            end
            if app.Done(13)==1
                app.vislearn3_lamp.Color = [0 1 0];
            else
                app.vislearn3_lamp.Color = [1 1 1];
            end
            %            if app.Done(14)==1
            %                app.contrch3_lamp.Color = [0 1 0];
            %            else
            %                app.contrch3_lamp.Color = [1 1 1];
            %            end
            if app.Done(15)==1
                app.visualWM_lamp.Color = [0 1 0];
            else
                app.visualWM_lamp.Color = [1 1 1];
            end
            results=true;
        end
        
        function results = saveInfo(app)
            Done = app.Done;
            save([app.savePath,'/',app.ID{1}, '_metafile'],'Done','-append');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refreshStatus(app);
        end

        % Button pushed function: Questionnaire
        function QuestionnaireButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Questionaire...');
            try
                app.quest_lamp.Color = [1 1 0];
                if app.Done(1)==1
                    mbo=msgbox({'','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:'},'CMI-EEG',1,{app.ID{1},date});
                if app.Done(1)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(1)=0;
                PANAS
                app.Done(1)=1;
                saveInfo(app);
            catch
                if not(app.Done(1))
                    app.Done(1)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: Resting
        function RestingButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Resting EEG...');
            try
                app.resting_lamp.Color = [1 1 0];
                if app.Done(2)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(2)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(2)=0;
                Resting_EEG
                app.Done(2)=1;
                saveInfo(app);
            catch
                if not(app.Done(2))
                    app.Done(2)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: VisLearn1
        function VisLearn1ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Visual Learning Paradigma...');
            try
                app.vislearn1_lamp.Color = [1 1 0];
                if app.Done(3)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(3)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=1;
                app.Done(3)=0;
                Sequence_Learning_CMI_Circle
                app.Done(3)=1;
                saveInfo(app);
            catch
                if not(app.Done(3))
                    app.Done(3)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: ProcSpeed1
        function ProcSpeed1ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Processing Speed Paradigma...');
            try
                app.procspeed1_lamp.Color = [1 1 0];
                if app.Done(4)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(4)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=1;
                app.Done(4)=0;
                WISC_ProcessingSpeed_paradigm
                app.Done(4)=1;
                saveInfo(app);
            catch
                if not(app.Done(4))
                    app.Done(4)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: SurrSup1
        function SurrSup1ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Surround Suppression Paradigma...');
            try
                app.surrsupp1_lamp.Color = [1 1 0];
                if app.Done(5)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(5)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=1;
                app.Done(5)=0;
                SurroundSupp4circCMI_new_fixed_order_ThreeBlocks
                app.Done(5)=1;
                saveInfo(app);
            catch
                if not(app.Done(5))
                    app.Done(5)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Callback function
        function ContrCh1ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Contrast Change Paradigma...');
            try
                app.contrch1_lamp.Color = [1 1 0];
                if app.Done(6)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(6)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=1;
                app.Done(6)=0;
                ContrastChangeDetect_2AFC_CMI_new_AllBlocks
                app.Done(6)=1;
                saveInfo(app);
            catch
                if not(app.Done(6))
                    app.Done(6)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: VisLearn2
        function VisLearn2ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Visual Learning Paradigma...');
            try
                app.vislearn2_lamp.Color = [1 1 0];
                if app.Done(7)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(7)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=2;
                app.Done(7)=0;
                Sequence_Learning_CMI_Circle
                app.Done(7)=1;
                saveInfo(app);
            catch
                if not(app.Done(7))
                    app.Done(7)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
            msgbox('Before correcting subjects impedances, first measure the current impedances and press "save and close".');
        end

        % Button pushed function: AntiSacc
        function AntiSaccButtonPushed(app, event)
            if app.Status(1)~=1
                warndlg('ET is not connected! Antisaccade only works with ET','ERROR');
            else
                deactivateControls(app);
                saveInfo(app);
                clc;
                disp('Starting Antisaccade Paradigma...');
                try
                    app.antisacc_lamp.Color = [1 1 0];
                    if app.Done(8)==1
                        mbo=msgbox('Warning: This task already has been performed succesfuly, do you really want to redo the task?');
                    end
                    app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                    if app.Done(8)==1
                        close(mbo);
                    end
                    if (isempty(app.run)); error('X');end;
                    app.Done(8)=0;
                    AntiSaccade
                    app.Done(8)=1;
                    saveInfo(app);
                catch
                    if not(app.Done(8))
                        app.Done(8)=0;
                    end
                    disp('FEHLER');
                end
                refreshLights(app);
                activateControls(app);
                msgbox('Before correcting subjects impedances, first measure the current impedances and press "save and close".');
                
            end;
        end

        % Button pushed function: SimpleSpan
        function SimpleSpanButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Simple Span Paradigma...');
            try
                app.simplespan_lamp.Color = [1 1 0];
                if app.Done(9)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(9)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(9)=0;
                SimpleSpan
                app.Done(9)=1;
                saveInfo(app);
            catch
                if not(app.Done(9))
                    app.Done(9)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: SurrSup2, SurroundSuppression2Button
        function SurrSup3ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Surround Suppression Paradigma...');
            try
                app.surrsupp3_lamp.Color = [1 1 0];
                if app.Done(16)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(16)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=3;
                app.Done(16)=0;
                SurroundSupp4circCMI_new_fixed_order_ThreeBlocks
                app.Done(16)=1;
                saveInfo(app);
            catch
                if not(app.Done(16))
                    app.Done(16)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: ProcSpeed2
        function ProcSpeed2ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Processing Speed Paradigma...');
            try
                app.procspeed2_lamp.Color = [1 1 0];
                if app.Done(11)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(11)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=2;
                app.Done(11)=0;
                WISC_ProcessingSpeed_paradigm
                app.Done(11)=1;
                saveInfo(app);
            catch
                if not(app.Done(11))
                    app.Done(11)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Callback function
        function ContrChange2ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Contrast Change Paradigma...');
            try
                app.contrch2_lamp.Color = [1 1 0];
                if app.Done(12)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(12)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=2;
                app.Done(12)=0;
                ContrastChangeDetect_2AFC_CMI_new_AllBlocks
                app.Done(12)=1;
                saveInfo(app);
            catch
                if not(app.Done(12))
                    app.Done(12)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: VisLearn3
        function VisLearn3ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Visual Learning Paradigma...');
            try
                app.vislearn3_lamp.Color = [1 1 0];
                if app.Done(13)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(13)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=3;
                app.Done(13)=0;
                Sequence_Learning_CMI_Circle
                app.Done(13)=1;
                saveInfo(app);
            catch
                if not(app.Done(13))
                    app.Done(13)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Callback function
        function ContrCh3ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Contrast Change Paradigma...');
            try
                if app.Done(14)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(14)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=3;
                app.Done(14)=0;
                ContrastChangeDetect_2AFC_CMI_new_AllBlocks
                app.Done(14)=1;
                saveInfo(app);
            catch
                if not(app.Done(14))
                    app.Done(14)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: VisLearn1_ex
        function VisLearn1_exButtonPushed(app, event)
            answer = questdlg('Do example with eyetracker?', ...
               	'?', ...
               	'Yes','No','Yes');
            % Handle response
            switch answer
                case 'Yes'
                    
                    app.useEL_Ex = 1;
                case 'No'
                    
                    app.useEL_Ex = 0;
                    
            end
            deactivateControls(app);
            clc;
            disp('Starting Visual Learning Example...');
            Sequence_Learning_CMI_Circle_Example
            clc;
            disp('Visual Learning Example finished!');
            activateControls(app);
        end

        % Button pushed function: ProcSpeed1_ex
        function ProcSpeed1_exButtonPushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting Processing Speed Example...');
            WISC_ProcessingSpeed_paradigm_Example
            clc;
            disp('Processing Speed Example finished!');
            activateControls(app);
        end

        % Callback function
        function ContrCh1_exButtonPushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting Contrast Change Example...');
            app.run{6}=0;
            ContrastChangeDetect_2AFC_CMI_new_AllBlocks
            clc;
            disp('Contrast Change Example finished!');
            activateControls(app);
        end

        % Button pushed function: VisLearn2_ex
        function VisLearn2_exButtonPushed(app, event)
            
            deactivateControls(app);
            clc;
            disp('Starting Visual Learning Example...');
            VisLearn1_exButtonPushed(app, event)
            clc;
            disp('Visual Learning Example finished!');
            activateControls(app);
        end

        % Button pushed function: AntiSacc_ex
        function AntiSacc_exButtonPushed(app, event)
            deactivateControls(app);
            if app.Status(1)~=1
                warndlg('ET is not connected! Antisaccade only works with ET','ERROR');
            else
                clc;
                disp('Starting Antisaccade Example...');
                AntiSaccade_Example
                clc;
                disp('Antisaccade Example finished!');
            end
            activateControls(app);
        end

        % Button pushed function: ProcSpeed2_ex
        function ProcSpeed2_exButtonPushed(app, event)
            ProcSpeed1_exButtonPushed(app, event)
        end

        % Callback function
        function ContrCh2_exButtonPushed(app, event)
            ContrCh1_exButtonPushed(app, event)
        end

        % Button pushed function: VisLearn3_ex
        function VisLearn3_exButtonPushed(app, event)
            VisLearn1_exButtonPushed(app, event)
        end

        % Callback function
        function ContrCh3_exButtonPushed(app, event)
            ContrCh1_exButtonPushed(app, event)
        end

        % Button pushed function: ConnectEEG
        function ConnectEEGButtonPushed(app, event)
            try
                app.EEGVerbundenLamp.Color = [1 1 0];
                try
                    i = NetStation('Synchronize');
                    if i == 0
                        disp('already connected');
                    else
                        disp('need to connect');
                    end
                catch
                end
                [status,info] = NetStation('Connect','100.1.1.3',55513);
                WaitSecs(1);
                if status ~= 0
                    error(info);
                end
                app.Status(2)=1;
            catch
                disp('FEHLER');
                app.Status(2)=0;
            end
            refreshLights(app);
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            refreshStatus(app);
        end

        % Callback function: BeendenButton_2, EEG_GUIUIFigure
        function ABBRECHENButtonPushed(app, event)
            
            choice = questdlg('Wirklich das Experiment Beenden?', ...
               	'ACHTUNG', ...
               	'Ja','Nein','Nein');
            % Handle response
            switch choice
                case 'Ja'
                    close all force;
                    try
                        NetStation('StopRecording');
                        NetStation('Disconnect');
                    catch
                    end
                    try
                        Eyelink('StopRecording');
                    catch
                    end
                    sca;
                case 'Nein'
                    return;
            end
            msgbox('3D Scan der Versuchsperson nicht vergessen!');


        end

        % Button pushed function: Simple_Span_ex
        function SimpleSpan_exButtonPushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting Simple Sample Example...');
            SimpleSpan_Example;
            activateControls(app);
        end

        % Button pushed function: visualWM
        function visualWMButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Visual_WM Paradigma...');
            try
                app.visualWM_lamp.Color = [1 1 0];
                if app.Done(15)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(15)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.Done(15)=0;
                Run_ChangeDetection;
                app.Done(15)=1;
                saveInfo(app);
            catch
                if not(app.Done(15))
                    app.Done(15)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: visualWM_ex
        function visualWM_exButtonPushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting VisualWM Example...');
            Run_ChangeDetection_Example;
            activateControls(app);
        end

        % Button pushed function: SurroundSuppression2Button
        function SurrSup2ButtonPushed(app, event)
            deactivateControls(app);
            saveInfo(app);
            clc;
            disp('Starting Surround Suppression Paradigma...');
            try
                app.surrsupp2_lamp.Color = [1 1 0];
                if app.Done(10)==1
                    mbo=msgbox({'','','','','','','','','','','','','','','','','','','','','Warning: This task already has been performed succesfuly, do you really want to redo the task?'});
                end
                app.run=inputdlg({'Enter Subjects ID:', 'Enter Date:','Use EEG:', 'Use Eye-Tracker:', 'Eye-Tracker Calibration:'},'CMI-EEG',1,{app.ID{1},date,'y','y', 'y'});
                if app.Done(10)==1
                    close(mbo);
                end
                if (isempty(app.run)); error('X');end;
                app.run{6}=2;
                app.Done(10)=0;
                SurroundSupp4circCMI_new_fixed_order_ThreeBlocks
                app.Done(10)=1;
                saveInfo(app);
            catch
                if not(app.Done(10))
                    app.Done(10)=0;
                end
                disp('FEHLER');
            end
            refreshLights(app);
            activateControls(app);
        end

        % Button pushed function: LongtermButton
        function Longterm1_pushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting Visual Learning Longterm recall 1...');
            app.run{1}=app.ID{1};
            app.run{2}=date;
            app.run{6}=2;
            Sequence_Learning_CMI_Circle_LT;
            activateControls(app);
        end

        % Button pushed function: LongtermButton_2
        function Longterm2_pushed(app, event)
            deactivateControls(app);
            clc;
            disp('Starting Visual Learning Longterm recall 2...');
            app.run{1}=app.ID{1};
            app.run{2}=date;
            app.run{6}=3;
            Sequence_Learning_CMI_Circle_LT;
            activateControls(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEG_GUIUIFigure = uifigure;
            app.EEG_GUIUIFigure.Name = 'EEG_GUI';
            app.EEG_GUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ABBRECHENButtonPushed, true);
            setAutoResize(app, app.EEG_GUIUIFigure, false)
            app.VPLabel = uilabel(app.EEG_GUIUIFigure);
            app.VPLabel.Text = 'VP:';
            app.Label = uilabel(app.EEG_GUIUIFigure);
            app.Label.Text = '';
            app.TasksPanel = uipanel(app.EEG_GUIUIFigure);
            app.TasksPanel.Title = 'Tasks';
            app.Resting = uibutton(app.TasksPanel, 'push');
            app.Resting.ButtonPushedFcn = createCallbackFcn(app, @RestingButtonPushed, true);
            app.Resting.Text = 'Resting EEG';
            app.Questionnaire = uibutton(app.TasksPanel, 'push');
            app.Questionnaire.ButtonPushedFcn = createCallbackFcn(app, @QuestionnaireButtonPushed, true);
            app.Questionnaire.Text = 'Fragebögen';
            app.SurrSup1 = uibutton(app.TasksPanel, 'push');
            app.SurrSup1.ButtonPushedFcn = createCallbackFcn(app, @SurrSup1ButtonPushed, true);
            app.SurrSup1.Text = 'Surround Suppression #1';
            app.VisLearn1 = uibutton(app.TasksPanel, 'push');
            app.VisLearn1.ButtonPushedFcn = createCallbackFcn(app, @VisLearn1ButtonPushed, true);
            app.VisLearn1.Text = 'Visual Learning #1';
            app.VisLearn2 = uibutton(app.TasksPanel, 'push');
            app.VisLearn2.ButtonPushedFcn = createCallbackFcn(app, @VisLearn2ButtonPushed, true);
            app.VisLearn2.Text = 'Visual Learning #2';
            app.SimpleSpan = uibutton(app.TasksPanel, 'push');
            app.SimpleSpan.ButtonPushedFcn = createCallbackFcn(app, @SimpleSpanButtonPushed, true);
            app.SimpleSpan.Text = 'Simple Span';
            app.AntiSacc = uibutton(app.TasksPanel, 'push');
            app.AntiSacc.ButtonPushedFcn = createCallbackFcn(app, @AntiSaccButtonPushed, true);
            app.AntiSacc.Text = 'Antisaccade';
            app.ProcSpeed2 = uibutton(app.TasksPanel, 'push');
            app.ProcSpeed2.ButtonPushedFcn = createCallbackFcn(app, @ProcSpeed2ButtonPushed, true);
            app.ProcSpeed2.Text = 'Processing Speed #2';
            app.SurrSup2 = uibutton(app.TasksPanel, 'push');
            app.SurrSup2.ButtonPushedFcn = createCallbackFcn(app, @SurrSup3ButtonPushed, true);
            app.SurrSup2.Text = 'Surround Suppresion #3';
            app.VisLearn3 = uibutton(app.TasksPanel, 'push');
            app.VisLearn3.ButtonPushedFcn = createCallbackFcn(app, @VisLearn3ButtonPushed, true);
            app.VisLearn3.Text = 'Visual Learning #3';
            app.ProcSpeed1 = uibutton(app.TasksPanel, 'push');
            app.ProcSpeed1.ButtonPushedFcn = createCallbackFcn(app, @ProcSpeed1ButtonPushed, true);
            app.ProcSpeed1.Text = 'Processing Speed #1';
            app.VisLearn1_ex = uibutton(app.TasksPanel, 'push');
            app.VisLearn1_ex.ButtonPushedFcn = createCallbackFcn(app, @VisLearn1_exButtonPushed, true);
            app.VisLearn1_ex.Text = 'Example';
            app.VisLearn2_ex = uibutton(app.TasksPanel, 'push');
            app.VisLearn2_ex.ButtonPushedFcn = createCallbackFcn(app, @VisLearn2_exButtonPushed, true);
            app.VisLearn2_ex.Text = 'Example';
            app.AntiSacc_ex = uibutton(app.TasksPanel, 'push');
            app.AntiSacc_ex.ButtonPushedFcn = createCallbackFcn(app, @AntiSacc_exButtonPushed, true);
            app.AntiSacc_ex.Text = 'Example';
            app.ProcSpeed2_ex = uibutton(app.TasksPanel, 'push');
            app.ProcSpeed2_ex.ButtonPushedFcn = createCallbackFcn(app, @ProcSpeed2_exButtonPushed, true);
            app.ProcSpeed2_ex.Text = 'Example';
            app.VisLearn3_ex = uibutton(app.TasksPanel, 'push');
            app.VisLearn3_ex.ButtonPushedFcn = createCallbackFcn(app, @VisLearn3_exButtonPushed, true);
            app.VisLearn3_ex.Text = 'Example';
            app.ProcSpeed1_ex = uibutton(app.TasksPanel, 'push');
            app.ProcSpeed1_ex.ButtonPushedFcn = createCallbackFcn(app, @ProcSpeed1_exButtonPushed, true);
            app.ProcSpeed1_ex.Text = 'Example';
            app.quest_lamp = uilamp(app.TasksPanel);
            app.resting_lamp = uilamp(app.TasksPanel);
            app.vislearn1_lamp = uilamp(app.TasksPanel);
            app.procspeed1_lamp = uilamp(app.TasksPanel);
            app.surrsupp1_lamp = uilamp(app.TasksPanel);
            app.vislearn2_lamp = uilamp(app.TasksPanel);
            app.antisacc_lamp = uilamp(app.TasksPanel);
            app.simplespan_lamp = uilamp(app.TasksPanel);
            app.surrsupp3_lamp = uilamp(app.TasksPanel);
            app.procspeed2_lamp = uilamp(app.TasksPanel);
            app.vislearn3_lamp = uilamp(app.TasksPanel);
            app.ImpedanzenPauseImpedanzenLabel = uilabel(app.TasksPanel);
            app.ImpedanzenPauseImpedanzenLabel.Text = 'Impedanzen / Pause / Impedanzen';
            app.ImpedanzenPauseImpedanzenLabel_2 = uilabel(app.TasksPanel);
            app.ImpedanzenPauseImpedanzenLabel_2.Text = 'Impedanzen / Pause / Impedanzen';
            app.ImpedanzenENDELabel = uilabel(app.TasksPanel);
            app.ImpedanzenENDELabel.Text = 'Impedanzen / ENDE';
            app.Simple_Span_ex = uibutton(app.TasksPanel, 'push');
            app.Simple_Span_ex.ButtonPushedFcn = createCallbackFcn(app, @SimpleSpan_exButtonPushed, true);
            app.Simple_Span_ex.Text = 'Example';
            app.visualWM = uibutton(app.TasksPanel, 'push');
            app.visualWM.ButtonPushedFcn = createCallbackFcn(app, @visualWMButtonPushed, true);
            app.visualWM.Text = 'Visual WM';
            app.visualWM_lamp = uilamp(app.TasksPanel);
            app.visualWM_ex = uibutton(app.TasksPanel, 'push');
            app.visualWM_ex.ButtonPushedFcn = createCallbackFcn(app, @visualWM_exButtonPushed, true);
            app.visualWM_ex.Text = 'Example';
            app.surrsupp2_lamp = uilamp(app.TasksPanel);
            app.SurroundSuppression2Button = uibutton(app.TasksPanel, 'push');
            app.SurroundSuppression2Button.ButtonPushedFcn = createCallbackFcn(app, @SurrSup2ButtonPushed, true);
            app.SurroundSuppression2Button.Text = 'Surround Suppression #2';
            app.LongtermButton = uibutton(app.TasksPanel, 'push');
            app.LongtermButton.ButtonPushedFcn = createCallbackFcn(app, @Longterm1_pushed, true);
            app.LongtermButton.Text = 'Longterm';
            app.LongtermButton_2 = uibutton(app.TasksPanel, 'push');
            app.LongtermButton_2.ButtonPushedFcn = createCallbackFcn(app, @Longterm2_pushed, true);
            app.LongtermButton_2.Text = 'Longterm';
            app.VerbindungPanel = uipanel(app.EEG_GUIUIFigure);
            app.VerbindungPanel.Title = 'Verbindung';
            app.ConnectEEG = uibutton(app.VerbindungPanel, 'push');
            app.ConnectEEG.ButtonPushedFcn = createCallbackFcn(app, @ConnectEEGButtonPushed, true);
            app.ConnectEEG.Text = 'Verb.';
            app.EEGVerbundenLampLabel = uilabel(app.VerbindungPanel);
            app.EEGVerbundenLampLabel.Text = 'EEG Verbunden';
            app.EEGVerbundenLamp = uilamp(app.VerbindungPanel);
            app.ETVerbundenLampLabel = uilabel(app.VerbindungPanel);
            app.ETVerbundenLampLabel.Text = 'ET Verbunden';
            app.ETVerbundenLamp = uilamp(app.VerbindungPanel);
            app.VButton = uibutton(app.VerbindungPanel, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.BeendenButton_2 = uibutton(app.EEG_GUIUIFigure, 'push');
            app.BeendenButton_2.ButtonPushedFcn = createCallbackFcn(app, @ABBRECHENButtonPushed, true);
            app.BeendenButton_2.Text = 'Beenden';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MAIN()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEG_GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEG_GUIUIFigure)
        end
    end
end